classdef LabAssignment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        qControlPanel              matlab.ui.container.Panel
        slider1                    matlab.ui.control.Slider
        Q1EditFieldLabel           matlab.ui.control.Label
        q1_edit                    matlab.ui.control.NumericEditField
        slider2                    matlab.ui.control.Slider
        Q2Label                    matlab.ui.control.Label
        q2_edit                    matlab.ui.control.NumericEditField
        slider3                    matlab.ui.control.Slider
        Q3Label                    matlab.ui.control.Label
        q3_edit                    matlab.ui.control.NumericEditField
        slider4                    matlab.ui.control.Slider
        Q4Label                    matlab.ui.control.Label
        q4_edit                    matlab.ui.control.NumericEditField
        slider5                    matlab.ui.control.Slider
        Q5Label                    matlab.ui.control.Label
        q5_edit                    matlab.ui.control.NumericEditField
        slider6                    matlab.ui.control.Slider
        Q6Label                    matlab.ui.control.Label
        q6_edit                    matlab.ui.control.NumericEditField
        incrementQ1                matlab.ui.control.Button
        decrementQ1                matlab.ui.control.Button
        incrementQ2                matlab.ui.control.Button
        incrementQ5                matlab.ui.control.Button
        incrementQ4                matlab.ui.control.Button
        incrementQ3                matlab.ui.control.Button
        incrementQ6                matlab.ui.control.Button
        decrementQ2                matlab.ui.control.Button
        decrementQ3                matlab.ui.control.Button
        decrementQ4                matlab.ui.control.Button
        decrementQ5                matlab.ui.control.Button
        decrementQ6                matlab.ui.control.Button
        eStopButton                matlab.ui.control.StateButton
        CartesianMovementPanel     matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        inputX                     matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        inputY                     matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        inputZ                     matlab.ui.control.NumericEditField
        GOButton_cartesian         matlab.ui.control.Button
        SelectplayerPanel          matlab.ui.container.Panel
        ROLLButton                 matlab.ui.control.Button
        GOButton_dice              matlab.ui.control.Button
        dispDice_1                 matlab.ui.control.NumericEditField
        dispDice_2                 matlab.ui.control.NumericEditField
        TotalEditFieldLabel        matlab.ui.control.Label
        dispTotal                  matlab.ui.control.NumericEditField
        PlayernumberDropDownLabel  matlab.ui.control.Label
        PlayerNumberDropDown       matlab.ui.control.DropDown
        SimulatecollisionSlider    matlab.ui.control.Slider
        SimulatecollisionLabel     matlab.ui.control.Label
        INITIALIZEButton           matlab.ui.control.Button
    end

    properties (Access = public)
        board
        cell
        kinova 
        var
        playersLocation
        playerNumber
        pieces
        brick
        person
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INITIALIZEButton
        function INITIALIZEButtonPushed(app, event)
            ClearFunction();
            [app.board,app.cell,app.kinova,app.var,app.pieces] = InitiateRobot();
            app.brick.pos = [0,-0.5,0];
            app.brick = LocateParts(app.brick,'brick');
            app.person.pos = [0,1,-0.755];
            app.person.phi = 0;
            app.person = LocateParts(app.person,'person');
            app.playersLocation = ones(4);
        end

        % Value changed function: eStopButton
        function eStopRead(app, event)
            value = app.eStopButton.Value;
            EStopState(value,1);   % Type 1 = Stop button pressed 
        end

        % Callback function: decrementQ6, q1_edit, slider1, slider2, 
        % slider3, slider4, slider5, slider6
        function updateSliders(app, event)
            % Update slider value
            q1Value = app.slider1.Value;
            q2Value = app.slider2.Value;
            q3Value = app.slider3.Value;
            q4Value = app.slider4.Value;
            q5Value = app.slider5.Value;
            q6Value = app.slider6.Value;
            SlidersControl(app.kinova,q1Value,q2Value,q3Value,q4Value,q5Value,q6Value);
        end

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            app.dispDice_1.Value = randi([1 6]);
            app.dispDice_2.Value = randi([1 6]);
            app.dispTotal.Value = app.dispDice_1.Value + app.dispDice_2.Value;
        end

        % Value changed function: PlayerNumberDropDown
        function PlayerNumberDropDownValueChanged(app, event)
            app.playerNumber = str2num(app.PlayerNumberDropDown.Value);
            switch app.playerNumber
                case 1
                    disp('Player 1 selected')
                case 2
                    disp('Player 2 selected')
                case 3
                    disp('Player 3 selected')
                case 4
                    disp('Player 4 selected')
            end
        end

        % Button pushed function: GOButton_dice
        function GOButton_dicePushed(app, event)
            aux = ['piece' num2str(app.playerNumber)];
            app.playersLocation(app.playerNumber) = Movement(app.playersLocation,app.playerNumber,app.dispTotal.Value,app.board,app.cell,app.kinova,app.var,app.pieces.(aux),app.brick);
        end

        % Button pushed function: GOButton_cartesian
        function GOButton_cartesianPushed(app, event)
            xValue = app.inputX.Value;
            yValue = app.inputY.Value;
            zValue = app.inputZ.Value;
            CartesianControl(app.kinova,xValue,yValue,zValue);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            x1 = app.brick.pos(1);
            y1 = app.brick.pos(2);
            z1 = app.brick.pos(3);
            x2 = app.person.pos(1);
            y2 = app.person.pos(2);
            phi = app.person.phi;
                       
            mov = 0.05;  % Movement m

            switch(key)
                % Brick
                case 'leftarrow'
                    x1 = x1 + mov;
                case 'rightarrow'
                    x1 = x1 - mov;
                case 'downarrow'
                    y1 = y1 + mov;
                case 'uparrow'
                    y1 = y1 - mov; 
                case 't'
                    z1 = z1 + mov;
                case 'g'
                    z1 = z1 - mov;
                % Person
                case 'i'
                    x2 = x2 + mov;
                case 'j'
                    x2 = x2 - mov;
                case 'k'
                    y2 = y2 + mov;
                case 'u'
                    y2 = y2 - mov;
                case 'y'
                    phi = phi + pi/16;
                case 'h'
                    phi = phi - pi/16;
            end
            
            app.brick.pos = [x1,y1,z1];
            app.brick.pose = makehgtform('translate',app.brick.pos);
            app.brick.updatedPoints = (app.brick.pose * [app.brick.verts,ones(app.brick.vertexCount,1)]')';
            app.brick.mesh_h.Vertices = app.brick.updatedPoints(:,1:3);
            app.person.pos = [x2,y2,-0.755];
            app.person.pose = makehgtform('translate',app.person.pos) * trotz(phi);
            app.person.updatedPoints = (app.person.pose * [app.person.verts,ones(app.person.vertexCount,1)]')';
            app.person.mesh_h.Vertices = app.person.updatedPoints(:,1:3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.qControlPanel = uipanel(app.UIFigure);
            app.qControlPanel.Title = 'q Control';
            app.slider1 = uislider(app.qControlPanel);
            app.slider1.Limits = [-154 154];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q1EditFieldLabel = uilabel(app.qControlPanel);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.q1_edit = uieditfield(app.qControlPanel, 'numeric');
            app.q1_edit.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.slider2 = uislider(app.qControlPanel);
            app.slider2.Limits = [-150 150];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q2Label = uilabel(app.qControlPanel);
            app.Q2Label.Text = 'Q2';
            app.q2_edit = uieditfield(app.qControlPanel, 'numeric');
            app.slider3 = uislider(app.qControlPanel);
            app.slider3.Limits = [-150 150];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q3Label = uilabel(app.qControlPanel);
            app.Q3Label.Text = 'Q3';
            app.q3_edit = uieditfield(app.qControlPanel, 'numeric');
            app.slider4 = uislider(app.qControlPanel);
            app.slider4.Limits = [-148 148];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q4Label = uilabel(app.qControlPanel);
            app.Q4Label.Text = 'Q4';
            app.q4_edit = uieditfield(app.qControlPanel, 'numeric');
            app.slider5 = uislider(app.qControlPanel);
            app.slider5.Limits = [-144 145];
            app.slider5.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q5Label = uilabel(app.qControlPanel);
            app.Q5Label.Text = 'Q5';
            app.q5_edit = uieditfield(app.qControlPanel, 'numeric');
            app.slider6 = uislider(app.qControlPanel);
            app.slider6.Limits = [-148 148];
            app.slider6.ValueChangedFcn = createCallbackFcn(app, @updateSliders, true);
            app.Q6Label = uilabel(app.qControlPanel);
            app.Q6Label.Text = 'Q6';
            app.q6_edit = uieditfield(app.qControlPanel, 'numeric');
            app.incrementQ1 = uibutton(app.qControlPanel, 'push');
            app.incrementQ1.Text = '+';
            app.decrementQ1 = uibutton(app.qControlPanel, 'push');
            app.decrementQ1.Text = '-';
            app.incrementQ2 = uibutton(app.qControlPanel, 'push');
            app.incrementQ2.Text = '+';
            app.incrementQ5 = uibutton(app.qControlPanel, 'push');
            app.incrementQ5.Text = '+';
            app.incrementQ4 = uibutton(app.qControlPanel, 'push');
            app.incrementQ4.Text = '+';
            app.incrementQ3 = uibutton(app.qControlPanel, 'push');
            app.incrementQ3.Text = '+';
            app.incrementQ6 = uibutton(app.qControlPanel, 'push');
            app.incrementQ6.Text = '+';
            app.decrementQ2 = uibutton(app.qControlPanel, 'push');
            app.decrementQ2.Text = '-';
            app.decrementQ3 = uibutton(app.qControlPanel, 'push');
            app.decrementQ3.Text = '-';
            app.decrementQ4 = uibutton(app.qControlPanel, 'push');
            app.decrementQ4.Text = '-';
            app.decrementQ5 = uibutton(app.qControlPanel, 'push');
            app.decrementQ5.Text = '-';
            app.decrementQ6 = uibutton(app.qControlPanel, 'push');
            app.decrementQ6.ButtonPushedFcn = createCallbackFcn(app, @updateSliders, true);
            app.decrementQ6.Text = '-';
            app.eStopButton = uibutton(app.UIFigure, 'state');
            app.eStopButton.ValueChangedFcn = createCallbackFcn(app, @eStopRead, true);
            app.eStopButton.Icon = 'eStop.JPG';
            app.eStopButton.Text = '';
            app.CartesianMovementPanel = uipanel(app.UIFigure);
            app.CartesianMovementPanel.Title = 'Cartesian inputs';
            app.XEditFieldLabel = uilabel(app.CartesianMovementPanel);
            app.XEditFieldLabel.Text = 'X';
            app.inputX = uieditfield(app.CartesianMovementPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CartesianMovementPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.inputY = uieditfield(app.CartesianMovementPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CartesianMovementPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.inputZ = uieditfield(app.CartesianMovementPanel, 'numeric');
            app.GOButton_cartesian = uibutton(app.CartesianMovementPanel, 'push');
            app.GOButton_cartesian.ButtonPushedFcn = createCallbackFcn(app, @GOButton_cartesianPushed, true);
            app.GOButton_cartesian.Text = 'GO';
            app.SelectplayerPanel = uipanel(app.UIFigure);
            app.SelectplayerPanel.Title = 'Select player';
            app.ROLLButton = uibutton(app.SelectplayerPanel, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Text = 'ROLL';
            app.GOButton_dice = uibutton(app.SelectplayerPanel, 'push');
            app.GOButton_dice.ButtonPushedFcn = createCallbackFcn(app, @GOButton_dicePushed, true);
            app.GOButton_dice.Text = 'GO';
            app.dispDice_1 = uieditfield(app.SelectplayerPanel, 'numeric');
            app.dispDice_1.Editable = 'off';
            app.dispDice_2 = uieditfield(app.SelectplayerPanel, 'numeric');
            app.dispDice_2.Editable = 'off';
            app.TotalEditFieldLabel = uilabel(app.SelectplayerPanel);
            app.TotalEditFieldLabel.Text = 'Total';
            app.dispTotal = uieditfield(app.SelectplayerPanel, 'numeric');
            app.dispTotal.Editable = 'off';
            app.PlayernumberDropDownLabel = uilabel(app.SelectplayerPanel);
            app.PlayernumberDropDownLabel.Text = 'Player number';
            app.PlayerNumberDropDown = uidropdown(app.SelectplayerPanel);
            app.PlayerNumberDropDown.Items = {'...', '1', '2', '3', '4'};
            app.PlayerNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerNumberDropDownValueChanged, true);
            app.PlayerNumberDropDown.Value = '...';
            app.SimulatecollisionSlider = uislider(app.UIFigure);
            app.SimulatecollisionLabel = uilabel(app.UIFigure);
            app.SimulatecollisionLabel.Text = 'Simulate collision';
            app.INITIALIZEButton = uibutton(app.UIFigure, 'push');
            app.INITIALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @INITIALIZEButtonPushed, true);
            app.INITIALIZEButton.Text = 'INITIALIZE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabAssignment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end